classdef LUNG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PortStatusLabel  matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
        SwitchLabel      matlab.ui.control.Label
        PortDropDown     matlab.ui.control.DropDown
        DropDownLabel    matlab.ui.control.Label
        Lamp             matlab.ui.control.Lamp
        LampLabel        matlab.ui.control.Label
        Image            matlab.ui.control.Image
        UIAxes4          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

properties (Access = private)
        a;
        b;
        x;
        numSamples;
        Fs;
        amplificationFactor;
        e;
        delay = 10;
        a_temp;
        
    end

   methods (Access = private)
    
       function plotGraph(app)
        % Başlangıç değeri
        app.x = zeros(1, 100);
        filteredXvalues = zeros(1,100);
        
        % Alınacak örnek sayısı
        app.numSamples = 1000;
        
        % Örnekleme frekansı (Hz)
        app.Fs = 1220;
        
        % Amplifikasyon faktörü
        app.amplificationFactor = 10;

           
        % Bandpass filter parameters
        filterOrder = 2;
        lowCutoff = 600;  % Set your desired low cutoff frequency
        highCutoff = 200;  % Set your desired high cutoff frequency

        % Normalize cutoff frequencies
    nyquistFreq = app.Fs / 2;
    lowCutoffNorm = lowCutoff / nyquistFreq;
    highCutoffNorm = highCutoff / nyquistFreq;

% Design the Butterworth bandpass filter
[c, d] = butter(filterOrder, [highCutoffNorm,lowCutoffNorm], 'bandpass');

        % swcih butonun değeri On ise data okuyup grafik çizdir
        % değilse hiçbir şey yapmaz while çalışmaz
    
        while strcmp(app.Switch.Value, 'On')
            % Analog pin A0'den voltaj oku
            app.b = readVoltage(app.a, 'A0');
            disp('app.b: ');
            disp(app.b);
            
            % Amplifikasyon uygula
            b_amplified = app.b * app.amplificationFactor;
            
            % Okunan değeri x'e ekle
            app.x = [app.x, b_amplified];
           
            % Grafikte sadece son 100 örneği tut
            if length(app.x) > 100
                app.x = app.x(end-99:end);
            end
            
            % İlk grafik çizimi
            
            plot(app.UIAxes, 1:length(app.x), app.x);
     
            
            % Sinyalin ortalamasını çiz
            %plot(1:length(app.x), mean(app.x)*ones(1,length(app.x)), 'r--', 'LineWidth', 2);
 
            
            % Eksen isimleri ve başlık
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'(V)');
            title(app.UIAxes,'Raw Graph');
            
            % Sinyalin maksimum ve minimum değerlerine dayalı olarak y eksenini sınırla
            % ylim([min(app.x), max(app.x)]);

            % FFT parametreleri
            N = length(app.x);
            f = app.Fs*(0:(N/2))/N;
            
            % FFT uygula
            Y = fft(app.x);
            P2 = abs(Y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Güç spektrumu grafiği çiz
          
            plot(app.UIAxes2, f, P1);
            title(app.UIAxes2,'Power Spectrum');
            xlabel(app.UIAxes2,'Frequency (Hz)');
            ylabel(app.UIAxes2,'Power');

            % Apply bandpass filter
            b_filtered = filter(c, d, app.x);
            % Okunan değeri x'e ekle
            minLength = min(length(app.x), length(b_filtered));
            app.e = [app.x(end-minLength+1:end), b_filtered(1:minLength)];
            
            % Grafikte sadece son 100 örneği tut
            % Amplifikasyon uygula
            b_amplified2 = b_filtered * app.amplificationFactor;
            filteredXvalues = [filteredXvalues, b_filtered];
            if length(filteredXvalues) > 100
                filteredXvalues = filteredXvalues(end-99:end);
            end

                % Grafik çizimi
                disp('filteredXvalues');
                disp(filteredXvalues);
                
                plot(app.UIAxes3, 1:length(filteredXvalues), filteredXvalues);
                %ylim([min(app.e), max(app.e)]);
                %plot(app.UIAxes3, 1:length(app.x), b_filtered);
                % Gplot(app.UIAxes3, app.e, b_filtered);

                % plot(app.UIAxes3, 1:length(app.e), b_filtered);
    

                % Sinyalin ortalamasını çiz
                % plot(1:length(app.e), mean(app.e)*ones(1,length(app.e)), 'r--', 'LineWidth', 2);


                % Sinyalin maksimum ve minimum değerlerine dayalı olarak y eksenini sınırla
                % ylim([min(app.e), max(app.e)]); % Y ekseni sınırları, sinyalin minimum ve maksimum değerlerine göre
                % FFT parametreleri
            N = length(app.e);
            f = app.Fs*(0:(N/2))/N;
            
            % FFT uygula
            Y = fft(app.e);
            P2 = abs(Y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Güç spektrumu grafiği çiz
          
            plot(app.UIAxes4, f, P1);
            title(app.UIAxes4,'Power Spectrum');
            xlabel(app.UIAxes4,'Frequency (Hz)');
            ylabel(app.UIAxes4,'Power');
            
            % Çizimi güncelle
            drawnow;
            
            % Bekleme süresi ekle
            pause(0.01);
        end
    end
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function SwitchValueChanged(app, event)

            
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
                value = app.PortDropDown.Value;
            try
                  app.a = arduino(value, 'UNO');
                  % eğer arduinoya bağlanır ise ledin rengini mavi
                  % yapar 0.07,0.62,1 kodu mavi demek
                  app.Lamp.Color=[0.07,0.62,1];
            catch
                  disp('problem on the arduino');
                  % eğer arduinoya bağlanamaz ise ledin rengini kırmızı
                  % yapar 1,0,0 kodu kırmızı demek
                  app.Lamp.Color=[1,0,0];
            end
        end

        % Callback function: not associated with a component
        function SwitchValueChanged2(app, event)
      value = app.Switch.Value;
            if strcmp(value, 'On')
                %yeşil renk. eğer swcih buton açık ise
                app.Lamp.Color = [0,1,0];
                app.plotGraph();
            else 
                %kırmızı renk. eğer swcih buton kapalı ise
                app.Lamp.Color = [1,0,0];
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged3(app, event)
           value = app.Switch.Value;
            if strcmp(value, 'On')
                %yeşil renk. eğer swcih buton açık ise
                app.Lamp.Color = [0,1,0];
                app.plotGraph();
            else 
                %kırmızı renk. eğer swcih buton kapalı ise
                app.Lamp.Color = [1,0,0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-Time Raw Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT of Real Time Signal')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Voltage')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered Singal (Butterworth)')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Voltage')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'FFT of Filtered Signal')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Voltage')
            zlabel(app.UIAxes4, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pngtree-the-lungs-are-in-this-image-image_2619095.jpg');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM10', 'COM8', 'COM6', 'COM5'};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = 'COM10';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged3, true);
            app.PortStatusLabel = uilabel(app.UIFigure);
            app.PortStatusLabel.Text = 'Port Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUNG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end